classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        TimeCorrelationTab  matlab.ui.container.Tab
        ParametersPanel     matlab.ui.container.Panel
        GroupDropDownLabel  matlab.ui.control.Label
        GroupDropDown       matlab.ui.control.DropDown
        AnalyzeButton       matlab.ui.control.Button
        LagSliderLabel      matlab.ui.control.Label
        LagSlider           matlab.ui.control.Slider
        MidDropDownLabel    matlab.ui.control.Label
        MidDropDown         matlab.ui.control.DropDown
        LagEditField        matlab.ui.control.NumericEditField
        StatusLabel         matlab.ui.control.Label
        BeforePanel         matlab.ui.container.Panel
        BeforeAxes          matlab.ui.control.UIAxes
        MidPanel            matlab.ui.container.Panel
        MidAxes             matlab.ui.control.UIAxes
        DriftTab            matlab.ui.container.Tab
    end


    properties (Access = public)
        groups % groups of simultaneously recorded cells
        k % group string names
        gid = 1 %current group id
        mid = 1%which mid
        lag = 2.4
        dt = 0.02
        grid_thresh = 0.5
        sesh
        parent = 0;
        binspike = 0.06
        sigma = 2
        bindeg = 15
        version = 'n'
        number_degree_bins
        lag_max_secs 
        time_bin_secs
        fig 
    end

    methods (Access = private)
    
        function run(app)            
            delete(findobj(0,'type','axes'));
            g =  app.groups.(app.k{app.gid});
            app.grid_thresh = 0.5;
            good = [g(1)]; goodmid = [g(1)];
            for j = 1:length(g)
                if g(j).before.gridscore > app.grid_thresh
                    good(end+1) = g(j);
                end
                if true %g(j).middle{mid}.gridscore > 0.01
                    %goodmid(end+1) = g(j);
                end
            end
            good = good(2:end); goodmid = goodmid(2:end);
            v = 'before';
            if ~isempty(good) && length(good) >= 2
                set(app.StatusLabel, 'Text', 'computing....');
                %enable(handles, false)
                app.sesh = v;
                app.fig = app.TabGroup.Parent;
                app.parent = app.BeforePanel;
                %app.parent.Title = v;
                [~,v] = plotByDirectionMain(app);
                set(app.StatusLabel, 'Text', sprintf('%s',v));
                %MID
                app.fig = app.TabGroup.Parent;
                app.parent = app.BeforePanel;
                v = sprintf('mid%d',app.mid);
                app.sesh = app.mid;
                app.MidPanel.Text = v;
                [~,v] = plotByDirectionMain(app);
                set(app.StatusLabel, 'FontColor',[0,0,1]);
                set(app.StatusLabel, 'Text', v);
            else
                set(app.StatusLabel, 'FontColor',[1,0,0]);
                set(app.StatusLabel, 'Text', sprintf('good cells[%s,%d]',v,length(good)));
            end
            %enable(handles, true);
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            muscbinminutes = 10;
            fn = sprintf('C:\\Noam\\Data\\muscimol\\noam\\cells_%dmin_a.mat', muscbinminutes);
            fprintf('loading muscimol %d minute bins\n',muscbinminutes); %ascii 48
            tic; cells = load(fn); cells = cells.cells; toc;
            app.groups = findSimultaneouslyRecordedCells(cells);
            app.k = fieldnames(app.groups); %dt = 0.02; 
            app.GroupDropDown.Items = cellstr(num2str((1:length(app.k))'));
            g = app.groups.(app.k{app.gid});
            app.MidDropDown.Items = cellstr(num2str((1:length(g(1).middle))'));
        end

        % Value changed function: GroupDropDown
        function GroupDropDownValueChanged(app, event)
            app.gid = str2double(app.GroupDropDown.Value);
            g = app.groups.(app.k{app.gid});
            app.MidDropDown.Items = cellstr(num2str((1:length(g(1).middle))'));
        end

        % Value changing function: LagSlider
        function LagSliderValueChanging(app, event)
            app.LagEditField.Value = event.Value;
            
        end

        % Value changed function: LagSlider
        function LagSliderValueChanged(app, event)
            app.lag = round2r(app.LagSlider.Value, app.dt);
            app.LagEditField.Value = app.lag;
            app.LagSlider.Value = app.lag;
        end

        % Value changed function: LagEditField
        function LagEditFieldValueChanged(app, event)
            app.lag = round2r(app.LagEditField.Value, app.dt);
            app.LagEditField.Value = app.lag;
            app.LagSlider.Value = app.lag;
            
        end

        % Value changed function: MidDropDown
        function MidDropDownValueChanged(app, event)
            app.mid = app.MidDropDown.Value;
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            run(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeCorrelationTab = uitab(app.TabGroup);
            app.TimeCorrelationTab.Title = 'Time Correlation';
            app.ParametersPanel = uipanel(app.TimeCorrelationTab);
            app.ParametersPanel.Title = 'Parameters';
            app.GroupDropDownLabel = uilabel(app.ParametersPanel);
            app.GroupDropDownLabel.Text = 'Group';
            app.GroupDropDown = uidropdown(app.ParametersPanel);
            app.GroupDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupDropDownValueChanged, true);
            app.AnalyzeButton = uibutton(app.ParametersPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.LagSliderLabel = uilabel(app.ParametersPanel);
            app.LagSliderLabel.Text = 'Lag';
            app.LagSlider = uislider(app.ParametersPanel);
            app.LagSlider.Limits = [0.5 5];
            app.LagSlider.MajorTicks = [0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.LagSlider.MajorTickLabels = {'0.5', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '5'};
            app.LagSlider.ValueChangedFcn = createCallbackFcn(app, @LagSliderValueChanged, true);
            app.LagSlider.ValueChangingFcn = createCallbackFcn(app, @LagSliderValueChanging, true);
            app.LagSlider.Value = 2.4;
            app.MidDropDownLabel = uilabel(app.ParametersPanel);
            app.MidDropDownLabel.Text = 'Mid';
            app.MidDropDown = uidropdown(app.ParametersPanel);
            app.MidDropDown.ValueChangedFcn = createCallbackFcn(app, @MidDropDownValueChanged, true);
            app.LagEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LagEditField.ValueChangedFcn = createCallbackFcn(app, @LagEditFieldValueChanged, true);
            app.LagEditField.Limits = [0.5 5];
            app.LagEditField.ValueDisplayFormat = '%.2f';
            app.LagEditField.Value = 2.4;
            app.StatusLabel = uilabel(app.ParametersPanel);
            app.StatusLabel.Text = 'Status';
            app.BeforePanel = uipanel(app.TimeCorrelationTab);
            app.BeforePanel.Title = 'Before';
            app.BeforeAxes = uiaxes(app.BeforePanel);
            title(app.BeforeAxes, 'Title')
            xlabel(app.BeforeAxes, 'X')
            ylabel(app.BeforeAxes, 'Y')
            app.MidPanel = uipanel(app.TimeCorrelationTab);
            app.MidPanel.Title = 'Mid';
            app.MidAxes = uiaxes(app.MidPanel);
            title(app.MidAxes, 'Title')
            xlabel(app.MidAxes, 'X')
            ylabel(app.MidAxes, 'Y')
            app.DriftTab = uitab(app.TabGroup);
            app.DriftTab.Title = 'Drift';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end